classdef TreeUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        zhibiao        matlab.ui.control.TextArea
        Label_2        matlab.ui.control.Label
        yushe          matlab.ui.container.Panel
        startpri       matlab.ui.control.Button
        pruning        matlab.ui.control.StateButton
        featurechoose  matlab.ui.control.ToggleSwitch
        datachoose     matlab.ui.control.DropDown
        Label          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: startpri
        function startpriButtonPushed(app, event)
            addpath('Dataset\');
            addpath('Improve\');
            disp("start!");
            if app.featurechoose.Value == "信息增益"
                % 读取数据
                if app.datachoose.Value == "breastcancer"
                    load('breastcancer.mat')
                    dataset_choose = breastcancer;
                    p = 'recurrence-events';
                    n = 'no-recurrence-events';
                end
                if app.datachoose.Value == "tic_tac_toe"
                    load('tic_tac_toe.mat')
                    dataset_choose = tic_tac_toe;
                    p = 'positive';
                    n = 'negative';
                end
                if app.datachoose.Value == "cmc"
                    load('cmc.mat')
                    dataset_choose = cmc;
                end
                size_data = size(dataset_choose); 
                
                %% 10次10折交叉验证
                k_t = 10;
                cross_time = 10;
                y_lable = dataset_choose(2:size_data(1),size_data(2));
                T_P=zeros(k_t,cross_time);
                tic;
                for i = 1:cross_time
                    %分为训练集和测试集(10折)，
                    y_1 = find(strcmp(y_lable(:),y_lable(1)));%与第一个标签相同的为一层次
                    y_2 = find(~strcmp(y_lable(:),y_lable(1)));%其余为另一个层次
                    y_1_length = length(y_1);
                    y_2_length = length(y_2);
                    y_1_perNum = floor(y_1_length/k_t);
                    y_2_perNum = floor(y_2_length/k_t);
                    y_1_randIndex = randperm(y_1_length);
                    y_2_randIndex = randperm(y_2_length);
                    D_index = zeros(y_1_perNum+y_2_perNum,k_t); %D中存放了10组数据索引
                    for j = 1:k_t                          %有数据被丢弃
                        D_index(:,j)=[...
                            y_1(y_1_randIndex(y_1_perNum*(j-1)+1:y_1_perNum*j));...
                            y_2(y_2_randIndex(y_2_perNum*(j-1)+1:y_2_perNum*j))];
                    end
                    D_index = D_index+1;
                    perNum_D = y_1_perNum+y_2_perNum;
                    % 训练10折交叉验证
                    for k=1:k_t
                        % 获取此时的数据集以及测试集
                        x_train = dataset_choose(...
                            [1; reshape(D_index(:,1:k-1),[],1);...
                             reshape(D_index(:,k+1:k_t),[],1)],:) ;     % 这里加上了属性标签行
                        x_test = dataset_choose(D_index(:,k),:);  % 选择最后两个当测试集
                        % 训练
                        size_data = size(x_train);
                        dataset = x_train(2:size_data(1),:); % 纯数据集
                        labels = x_train(1,1:size_data(2)-1); % 属性标签
                        % 生成决策树
                        mytree = ID3(dataset,labels);
                        % 预测测试集标签并计算精度
                        y_test = predict(x_test(:,1:end-1),mytree,labels);
                        T_P(i,k) = sum(strcmp(y_test',x_test(:,end)))/perNum_D;
                        if app.datachoose.Value ~= "cmc"
                            auc(i,k) = plot_roc(y_test',x_test(:,end),p,n);
                        end
                    end
                end
                [nodeids_,nodevalue_,branchvalue_] = print_tree(mytree);
                tree_plot(nodeids_,nodevalue_,branchvalue_);
                toc;
                cellArrayText{1} = sprintf('此模型10次10折交叉验证的平均精度为：%f\n\n',mean(mean(T_P)));
                cellArrayText{2} = sprintf('此模型10次10折交叉验证共花费时长为：%f秒\n\n',toc);
                if app.datachoose.Value ~= "cmc"
                    cellArrayText{3} = sprintf('此模型10次10折交叉验证的平均AUC值为：%f\n',mean(mean(auc(i,k))));
                end
                app.zhibiao.Value = cellArrayText;
            end
            if app.featurechoose.Value == "基尼指数"
               if app.pruning.Value == 0
                % 读取数据
                if app.datachoose.Value == "breastcancer"
                    load('breastcancer.mat')
                    dataset_choose = breastcancer;
                    p = 'recurrence-events';
                    n = 'no-recurrence-events';
                end
                if app.datachoose.Value == "tic_tac_toe"
                    load('tic_tac_toe.mat')
                    dataset_choose = tic_tac_toe;
                    p = 'positive';
                    n = 'negative';
                end
                if app.datachoose.Value == "cmc"
                    load('cmc.mat')
                    dataset_choose = cmc;
                end
                size_data = size(dataset_choose); 
                
                %% 10次10折交叉验证
                k_t=10;
                cross_time=10;
                y_lable=dataset_choose(2:size_data(1),size_data(2));
                T_P=zeros(k_t,cross_time);
                tic;
                for i=1:cross_time
                    %分为训练集和测试集(10折)，
                    y_1=find(strcmp(y_lable(:),y_lable(1)));%与第一个标签相同的为一层次
                    y_2=find(~strcmp(y_lable(:),y_lable(1)));%其余为另一个层次
                    y_1_length=length(y_1);
                    y_2_length=length(y_2);
                    y_1_perNum=floor(y_1_length/k_t);
                    y_2_perNum=floor(y_2_length/k_t);
                    y_1_randIndex=randperm(y_1_length);
                    y_2_randIndex=randperm(y_2_length);
                    D_index=zeros(y_1_perNum+y_2_perNum,k_t);
                    for j=1:k_t                          %有数据被丢弃
                        D_index(:,j)=[...
                            y_1(y_1_randIndex(y_1_perNum*(j-1)+1:y_1_perNum*j));...
                            y_2(y_2_randIndex(y_2_perNum*(j-1)+1:y_2_perNum*j))];
                    end
                    D_index=D_index+1;
                    perNum_D=y_1_perNum+y_2_perNum;
                    for k=1:k_t
                        if k~=k_t
                            x_train = dataset_choose(...
                                [1; reshape(D_index(:,1:k-1),[],1);...
                                reshape(D_index(:,k+2:k_t),[],1)],:) ;     % 这里加上了属性标签行
                        else
                            x_train = dataset_choose([1; reshape(D_index(:,2:k-1),[],1)],:) ;     % 这里加上了属性标签行
                        end
                        x_valid = dataset_choose(D_index(:,k),:);              % 选择验证集
                        x_test = dataset_choose(D_index(:,mod(k+1,k_t)+1),:);  % 选择测试集
                        % 训练
                        size_data = size(x_train);
                        dataset = x_train(2:size_data(1),:); % 纯数据集
                        labels = x_train(1,1:size_data(2)-1); % 属性标签
                        % 生成决策树
                        mytree = ID3_2(dataset,labels);
                        % 预测测试集标签并计算精度
                        y_test=predict_2(x_test(:,1:end-1),mytree,labels);
                        T_P(i,k)=sum(strcmp(y_test',x_test(:,end)))/perNum_D;
                        if app.datachoose.Value ~= "cmc"
                            auc(i,k) = plot_roc(y_test',x_test(:,end),p,n);
                        end
                    end
                end
                [nodeids_,nodevalue_,branchvalue_] = print_tree(mytree);
                tree_plot(nodeids_,nodevalue_,branchvalue_);
                toc;
                cellArrayText{1} = sprintf('此模型10次10折交叉验证的平均精度为：%f\n\n',mean(mean(T_P)));
                cellArrayText{2} = sprintf('此模型10次10折交叉验证共花费时长为：%f秒\n\n',toc);
                if app.datachoose.Value ~= "cmc"
                    cellArrayText{3} = sprintf('此模型10次10折交叉验证的平均AUC值为：%f\n',mean(mean(auc(i,k))));
                end
                app.zhibiao.Value = cellArrayText;
               end
               if app.pruning.Value == 1
                % 读取数据
                if app.datachoose.Value == "breastcancer"
                    load('breastcancer.mat')
                    dataset_choose = breastcancer;
                    p = 'recurrence-events';
                    n = 'no-recurrence-events';
                end
                if app.datachoose.Value == "tic_tac_toe"
                    load('tic_tac_toe.mat')
                    dataset_choose = tic_tac_toe;
                    p = 'positive';
                    n = 'negative';
                end
                if app.datachoose.Value == "cmc"
                    load('cmc.mat')
                    dataset_choose = cmc;
                end
                size_data = size(dataset_choose); 
                
                %% 10次10折交叉验证
                k_t=10;
                cross_time=10;
                y_lable=dataset_choose(2:size_data(1),size_data(2));
                T_P=zeros(k_t,cross_time);
                tic;
                for i=1:cross_time
                    %分为训练集和测试集(10折)，
                    y_1=find(strcmp(y_lable(:),y_lable(1)));%与第一个标签相同的为一层次
                    y_2=find(~strcmp(y_lable(:),y_lable(1)));%其余为另一个层次
                    y_1_length=length(y_1);
                    y_2_length=length(y_2);
                    y_1_perNum=floor(y_1_length/k_t);
                    y_2_perNum=floor(y_2_length/k_t);
                    y_1_randIndex=randperm(y_1_length);
                    y_2_randIndex=randperm(y_2_length);
                    D_index=zeros(y_1_perNum+y_2_perNum,k_t);
                    for j=1:k_t                          %有数据被丢弃
                        D_index(:,j)=[...
                            y_1(y_1_randIndex(y_1_perNum*(j-1)+1:y_1_perNum*j));...
                            y_2(y_2_randIndex(y_2_perNum*(j-1)+1:y_2_perNum*j))];
                    end
                    D_index=D_index+1;
                    perNum_D=y_1_perNum+y_2_perNum;
                    for k=1:k_t
                        if k~=k_t
                            x_train = dataset_choose(...
                                [1; reshape(D_index(:,1:k-1),[],1);...
                                reshape(D_index(:,k+2:k_t),[],1)],:) ;     % 这里加上了属性标签行
                        else
                            x_train = dataset_choose([1; reshape(D_index(:,2:k-1),[],1)],:) ;     % 这里加上了属性标签行
                        end
                        x_valid = dataset_choose(D_index(:,k),:);              % 选择验证集
                        x_test = dataset_choose(D_index(:,mod(k+1,k_t)+1),:);  % 选择测试集
                        % 训练
                        size_data = size(x_train);
                        dataset = x_train(2:size_data(1),:); % 纯数据集
                        labels = x_train(1,1:size_data(2)-1); % 属性标签
                        % 生成决策树
                        mytree = ID3_2(dataset,labels);
                        % 剪枝
                        [correct,mytree] = pruning(x_valid,mytree,labels);
                        % 预测测试集标签并计算精度
                        y_test=predict_2(x_test(:,1:end-1),mytree,labels);
                        T_P(i,k)=sum(strcmp(y_test',x_test(:,end)))/perNum_D;
                        if app.datachoose.Value ~= "cmc"
                            auc(i,k) = plot_roc(y_test',x_test(:,end),p,n);
                        end
                    end
                end
                [nodeids_,nodevalue_,branchvalue_] = print_tree(mytree);
                tree_plot(nodeids_,nodevalue_,branchvalue_);
                toc;
                cellArrayText{1} = sprintf('此模型10次10折交叉验证的平均精度为：%f\n\n',mean(mean(T_P)));
                cellArrayText{2} = sprintf('此模型10次10折交叉验证共花费时长为：%f秒\n\n',toc);
                if app.datachoose.Value ~= "cmc"
                    cellArrayText{3} = sprintf('此模型10次10折交叉验证的平均AUC值为：%f\n',mean(mean(auc(i,k))));
                end
                app.zhibiao.Value = cellArrayText;
               end
            end

        end

        % Value changed function: pruning
        function pruningValueChanged(app, event)
            value = app.pruning.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.yushe = uipanel(app.UIFigure);
            app.yushe.Title = '决策树预设';
            app.Label = uilabel(app.yushe);
            app.Label.Text = '选择数据集';
            app.datachoose = uidropdown(app.yushe);
            app.datachoose.Items = {'breastcancer', 'tic_tac_toe', 'cmc'};
            app.datachoose.Value = 'breastcancer';
            app.featurechoose = uiswitch(app.yushe, 'toggle');
            app.featurechoose.Items = {'信息增益', '基尼指数'};
            app.featurechoose.Value = '信息增益';
            app.pruning = uibutton(app.yushe, 'state');
            app.pruning.ValueChangedFcn = createCallbackFcn(app, @pruningValueChanged, true);
            app.pruning.Text = '剪枝';
            app.startpri = uibutton(app.yushe, 'push');
            app.startpri.ButtonPushedFcn = createCallbackFcn(app, @startpriButtonPushed, true);
            app.startpri.Text = '开始预测';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '决策树预测模型指标：';
            app.zhibiao = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TreeUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end